classdef EditParameters < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        EditPulseParametersUIFigure  matlab.ui.Figure
        UITable                      matlab.ui.control.Table
        OkButton                     matlab.ui.control.Button
        PPGTypeLabel                 matlab.ui.control.Label
        PPGTypeDropDown              matlab.ui.control.DropDown
    end

    
    properties (Access = private)
        CallingApp; % Description
        regularPara;
        compensationPara;
        resetPara;
        InterpolationPara;
    end
    
    methods (Access = private)
        
        function results = updatePara(app)
            value = app.PPGTypeDropDown.Value;
            switch value
                case 'Regular'
                    app.UITable.RowName = {'Regular mean','Regular Std'};
                    
                    app.UITable.Data = {app.regularPara.a1.mean,app.regularPara.a2.mean,app.regularPara.b1.mean,app.regularPara.b2.mean,app.regularPara.theta1.mean,app.regularPara.theta2.mean; ...
                        app.regularPara.a1.std, app.regularPara.a2.std, app.regularPara.b1.std, app.regularPara.b2.std, app.regularPara.theta1.std, app.regularPara.theta2.std;};

                case 'Compensation'
                    app.UITable.RowName = {'Reference mean','Reference Std','First Beat mean','First Beat Std','Second Beat mean','Second Beat Std'};
                    app.UITable.Data = [app.compensationPara.reference.a1.mean,app.compensationPara.reference.a2.mean,app.compensationPara.reference.b1.mean,app.compensationPara.reference.b2.mean,app.compensationPara.reference.theta1.mean,app.compensationPara.reference.theta2.mean,1; ...
                        app.compensationPara.reference.a1.std, app.compensationPara.reference.a2.std, app.compensationPara.reference.b1.std, app.compensationPara.reference.b2.std, app.compensationPara.reference.theta1.std, app.compensationPara.reference.theta2.std,NaN;...  
                        app.compensationPara.firstBeat.a1.mean,app.compensationPara.firstBeat.a2.mean,app.compensationPara.firstBeat.b1.mean,app.compensationPara.firstBeat.b2.mean,app.compensationPara.firstBeat.theta1.mean,app.compensationPara.firstBeat.theta2.mean,app.compensationPara.firstBeat.durationRatio; ...
                        app.compensationPara.firstBeat.a1.std, app.compensationPara.firstBeat.a2.std, app.compensationPara.firstBeat.b1.std, app.compensationPara.firstBeat.b2.std, app.compensationPara.firstBeat.theta1.std, app.compensationPara.firstBeat.theta2.std,NaN;...
                        app.compensationPara.secondBeat.a1.mean,app.compensationPara.secondBeat.a2.mean,app.compensationPara.secondBeat.b1.mean,app.compensationPara.secondBeat.b2.mean,app.compensationPara.secondBeat.theta1.mean,app.compensationPara.secondBeat.theta2.mean,app.compensationPara.secondBeat.durationRatio; ...
                        app.compensationPara.secondBeat.a1.std, app.compensationPara.secondBeat.a2.std, app.compensationPara.secondBeat.b1.std, app.compensationPara.secondBeat.b2.std, app.compensationPara.secondBeat.theta1.std, app.compensationPara.secondBeat.theta2.std,NaN;];
                case 'Reset'
                    app.UITable.RowName = {'Reference mean','Reference Std','First Beat mean','First Beat Std','Second Beat mean','Second Beat Std'};
                    app.UITable.Data = [app.resetPara.reference.a1.mean,app.resetPara.reference.a2.mean,app.resetPara.reference.b1.mean,app.resetPara.reference.b2.mean,app.resetPara.reference.theta1.mean,app.resetPara.reference.theta2.mean,1; ...
                        app.resetPara.reference.a1.std, app.resetPara.reference.a2.std, app.resetPara.reference.b1.std, app.resetPara.reference.b2.std, app.resetPara.reference.theta1.std, app.resetPara.reference.theta2.std,NaN;...  
                        app.resetPara.firstBeat.a1.mean,app.resetPara.firstBeat.a2.mean,app.resetPara.firstBeat.b1.mean,app.resetPara.firstBeat.b2.mean,app.resetPara.firstBeat.theta1.mean,app.resetPara.firstBeat.theta2.mean,app.resetPara.firstBeat.durationRatio; ...
                        app.resetPara.firstBeat.a1.std, app.resetPara.firstBeat.a2.std, app.resetPara.firstBeat.b1.std, app.resetPara.firstBeat.b2.std, app.resetPara.firstBeat.theta1.std, app.resetPara.firstBeat.theta2.std,NaN;...
                        app.resetPara.secondBeat.a1.mean,app.resetPara.secondBeat.a2.mean,app.resetPara.secondBeat.b1.mean,app.resetPara.secondBeat.b2.mean,app.resetPara.secondBeat.theta1.mean,app.resetPara.secondBeat.theta2.mean,app.resetPara.secondBeat.durationRatio; ...
                        app.resetPara.secondBeat.a1.std, app.resetPara.secondBeat.a2.std, app.resetPara.secondBeat.b1.std, app.resetPara.secondBeat.b2.std, app.resetPara.secondBeat.theta1.std, app.resetPara.secondBeat.theta2.std,NaN;];

                case 'Interpolation'
                    app.UITable.RowName = {'Reference mean','Reference Std','First Beat mean','First Beat Std','Second Beat mean','Second Beat Std'};
                    app.UITable.Data = [app.InterpolationPara.reference.a1.mean,app.InterpolationPara.reference.a2.mean,app.InterpolationPara.reference.b1.mean,app.InterpolationPara.reference.b2.mean,app.InterpolationPara.reference.theta1.mean,app.InterpolationPara.reference.theta2.mean,1; ...
                        app.InterpolationPara.reference.a1.std, app.InterpolationPara.reference.a2.std, app.InterpolationPara.reference.b1.std, app.InterpolationPara.reference.b2.std, app.InterpolationPara.reference.theta1.std, app.InterpolationPara.reference.theta2.std,NaN;...
                        app.InterpolationPara.firstBeat.a1.mean,app.InterpolationPara.firstBeat.a2.mean,app.InterpolationPara.firstBeat.b1.mean,app.InterpolationPara.firstBeat.b2.mean,app.InterpolationPara.firstBeat.theta1.mean,app.InterpolationPara.firstBeat.theta2.mean,app.InterpolationPara.firstBeat.durationRatio; ...
                        app.InterpolationPara.firstBeat.a1.std, app.InterpolationPara.firstBeat.a2.std, app.InterpolationPara.firstBeat.b1.std, app.InterpolationPara.firstBeat.b2.std, app.InterpolationPara.firstBeat.theta1.std, app.InterpolationPara.firstBeat.theta2.std,NaN;...
                        app.InterpolationPara.secondBeat.a1.mean,app.InterpolationPara.secondBeat.a2.mean,app.InterpolationPara.secondBeat.b1.mean,app.InterpolationPara.secondBeat.b2.mean,app.InterpolationPara.secondBeat.theta1.mean,app.InterpolationPara.secondBeat.theta2.mean,app.InterpolationPara.secondBeat.durationRatio; ...
                        app.InterpolationPara.secondBeat.a1.std, app.InterpolationPara.secondBeat.a2.std, app.InterpolationPara.secondBeat.b1.std, app.InterpolationPara.secondBeat.b2.std, app.InterpolationPara.secondBeat.theta1.std, app.InterpolationPara.secondBeat.theta2.std,NaN;];

            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, regularPara, compensationPara, resetPara, InterpolationPara)
            app.CallingApp = mainapp;
      
            
            app.regularPara = regularPara;
            app.compensationPara = compensationPara;
            app.resetPara = resetPara;
            app.InterpolationPara = InterpolationPara;
            
            app.UITable.RowName = {'Regular mean','Regular Std'};
            
            app.UITable.Data = {app.regularPara.a1.mean,app.regularPara.a2.mean,app.regularPara.b1.mean,app.regularPara.b2.mean,app.regularPara.theta1.mean,app.regularPara.theta2.mean; ...
                                app.regularPara.a1.std, app.regularPara.a2.std, app.regularPara.b1.std, app.regularPara.b2.std, app.regularPara.theta1.std, app.regularPara.theta2.std;};
            app.UITable.ColumnEditable = true;
            
            
        end

        % Value changed function: PPGTypeDropDown
        function PPGTypeDropDownValueChanged(app, event)
            updatePara(app);
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            indices = event.Indices;
            if indices(2) == 7
                if  indices(1) == 2 || indices(1) == 4 || indices(1) == 6
                    msgbox('This cell cannot be edited');
                    app.UITable.Data(indices(1),indices(2)) = NaN;
                elseif indices(1) == 1
                    msgbox('This cell cannot be edited');
                    app.UITable.Data(indices(1),indices(2)) = 1;
                end
            else
                
                assignin("base","indices",indices);
                newData = event.NewData;
                newData = rmmissing(newData);
                
                if length(newData) ~= 1
                    updatePara(app);
                    warndlg('The number of this parameters should be 1 and digital, value not changed!');
                    
                else
                    
                    value = app.PPGTypeDropDown.Value;
                    
                    switch value
                        case 'Regular'
                            app.regularPara.a1.mean = app.UITable.Data(1,1);
                            app.regularPara.a1.std = app.UITable.Data(2,1);
                            app.regularPara.a2.mean = app.UITable.Data(1,2);
                            app.regularPara.a2.std = app.UITable.Data(2,2);
                            app.regularPara.b1.mean = app.UITable.Data(1,3);
                            app.regularPara.b1.std = app.UITable.Data(2,3);
                            app.regularPara.b2.mean = app.UITable.Data(1,4);
                            app.regularPara.b2.std = app.UITable.Data(2,4);
                            app.regularPara.theta1.mean = app.UITable.Data(1,5);
                            app.regularPara.theta1.std = app.UITable.Data(2,5);
                            app.regularPara.theta2.mean = app.UITable.Data(1,6);
                            app.regularPara.theta2.std = app.UITable.Data(2,6);
                            
                            
                        case 'Compensation'
                            
                            app.compensationPara.reference.a1.mean = app.UITable.Data(1,1);
                            app.compensationPara.reference.a1.std = app.UITable.Data(2,1);
                            app.compensationPara.reference.a2.mean = app.UITable.Data(1,2);
                            app.compensationPara.reference.a2.std = app.UITable.Data(2,2);
                            app.compensationPara.reference.b1.mean = app.UITable.Data(1,3);
                            app.compensationPara.reference.b1.std = app.UITable.Data(2,3);
                            app.compensationPara.reference.b2.mean = app.UITable.Data(1,4);
                            app.compensationPara.reference.b2.std = app.UITable.Data(2,4);
                            app.compensationPara.reference.theta1.mean = app.UITable.Data(1,5);
                            app.compensationPara.reference.theta1.std = app.UITable.Data(2,5);
                            app.compensationPara.reference.theta2.mean = app.UITable.Data(1,6);
                            app.compensationPara.reference.theta2.std = app.UITable.Data(2,6);
                            
                            app.compensationPara.firstBeat.a1.mean = app.UITable.Data(3,1);
                            app.compensationPara.firstBeat.a1.std = app.UITable.Data(4,1);
                            app.compensationPara.firstBeat.a2.mean = app.UITable.Data(3,2);
                            app.compensationPara.firstBeat.a2.std = app.UITable.Data(4,2);
                            app.compensationPara.firstBeat.b1.mean = app.UITable.Data(3,3);
                            app.compensationPara.firstBeat.b1.std = app.UITable.Data(4,3);
                            app.compensationPara.firstBeat.b2.mean = app.UITable.Data(3,4);
                            app.compensationPara.firstBeat.b2.std = app.UITable.Data(4,4);
                            app.compensationPara.firstBeat.theta1.mean = app.UITable.Data(3,5);
                            app.compensationPara.firstBeat.theta1.std = app.UITable.Data(4,5);
                            app.compensationPara.firstBeat.theta2.mean = app.UITable.Data(3,6);
                            app.compensationPara.firstBeat.theta2.std = app.UITable.Data(4,6);
                            app.compensationPara.firstBeat.durationRatio = app.UITable.Data(3,7);
                            
                            app.compensationPara.secondBeat.a1.mean = app.UITable.Data(5,1);
                            app.compensationPara.secondBeat.a1.std = app.UITable.Data(6,1);
                            app.compensationPara.secondBeat.a2.mean = app.UITable.Data(5,2);
                            app.compensationPara.secondBeat.a2.std = app.UITable.Data(6,2);
                            app.compensationPara.secondBeat.b1.mean = app.UITable.Data(5,3);
                            app.compensationPara.secondBeat.b1.std = app.UITable.Data(6,3);
                            app.compensationPara.secondBeat.b2.mean = app.UITable.Data(5,4);
                            app.compensationPara.secondBeat.b2.std = app.UITable.Data(6,4);
                            app.compensationPara.secondBeat.theta1.mean = app.UITable.Data(5,5);
                            app.compensationPara.secondBeat.theta1.std = app.UITable.Data(6,5);
                            app.compensationPara.secondBeat.theta2.mean = app.UITable.Data(5,6);
                            app.compensationPara.secondBeat.theta2.std = app.UITable.Data(6,6);
                            app.compensationPara.secondBeat.durationRatio = app.UITable.Data(5,7);
                            
                            
                        case 'Reset'
                            app.resetPara.reference.a1.mean = app.UITable.Data(1,1);
                            app.resetPara.reference.a1.std = app.UITable.Data(2,1);
                            app.resetPara.reference.a2.mean = app.UITable.Data(1,2);
                            app.resetPara.reference.a2.std = app.UITable.Data(2,2);
                            app.resetPara.reference.b1.mean = app.UITable.Data(1,3);
                            app.resetPara.reference.b1.std = app.UITable.Data(2,3);
                            app.resetPara.reference.b2.mean = app.UITable.Data(1,4);
                            app.resetPara.reference.b2.std = app.UITable.Data(2,4);
                            app.resetPara.reference.theta1.mean = app.UITable.Data(1,5);
                            app.resetPara.reference.theta1.std = app.UITable.Data(2,5);
                            app.resetPara.reference.theta2.mean = app.UITable.Data(1,6);
                            app.resetPara.reference.theta2.std = app.UITable.Data(2,6);
                            
                            app.resetPara.firstBeat.a1.mean = app.UITable.Data(3,1);
                            app.resetPara.firstBeat.a1.std = app.UITable.Data(4,1);
                            app.resetPara.firstBeat.a2.mean = app.UITable.Data(3,2);
                            app.resetPara.firstBeat.a2.std = app.UITable.Data(4,2);
                            app.resetPara.firstBeat.b1.mean = app.UITable.Data(3,3);
                            app.resetPara.firstBeat.b1.std = app.UITable.Data(4,3);
                            app.resetPara.firstBeat.b2.mean = app.UITable.Data(3,4);
                            app.resetPara.firstBeat.b2.std = app.UITable.Data(4,4);
                            app.resetPara.firstBeat.theta1.mean = app.UITable.Data(3,5);
                            app.resetPara.firstBeat.theta1.std = app.UITable.Data(4,5);
                            app.resetPara.firstBeat.theta2.mean = app.UITable.Data(3,6);
                            app.resetPara.firstBeat.theta2.std = app.UITable.Data(4,6);
                            app.resetPara.firstBeat.durationRatio = app.UITable.Data(3,7);
                            
                            app.resetPara.secondBeat.a1.mean = app.UITable.Data(5,1);
                            app.resetPara.secondBeat.a1.std = app.UITable.Data(6,1);
                            app.resetPara.secondBeat.a2.mean = app.UITable.Data(5,2);
                            app.resetPara.secondBeat.a2.std = app.UITable.Data(6,2);
                            app.resetPara.secondBeat.b1.mean = app.UITable.Data(5,3);
                            app.resetPara.secondBeat.b1.std = app.UITable.Data(6,3);
                            app.resetPara.secondBeat.b2.mean = app.UITable.Data(5,4);
                            app.resetPara.secondBeat.b2.std = app.UITable.Data(6,4);
                            app.resetPara.secondBeat.theta1.mean = app.UITable.Data(5,5);
                            app.resetPara.secondBeat.theta1.std = app.UITable.Data(6,5);
                            app.resetPara.secondBeat.theta2.mean = app.UITable.Data(5,6);
                            app.resetPara.secondBeat.theta2.std = app.UITable.Data(6,6);
                            app.resetPara.secondBeat.durationRatio = app.UITable.Data(5,7);
                            
                            
                        case 'Interpolation'
                            app.InterpolationPara.reference.a1.mean = app.UITable.Data(1,1);
                            app.InterpolationPara.reference.a1.std = app.UITable.Data(2,1);
                            app.InterpolationPara.reference.a2.mean = app.UITable.Data(1,2);
                            app.InterpolationPara.reference.a2.std = app.UITable.Data(2,2);
                            app.InterpolationPara.reference.b1.mean = app.UITable.Data(1,3);
                            app.InterpolationPara.reference.b1.std = app.UITable.Data(2,3);
                            app.InterpolationPara.reference.b2.mean = app.UITable.Data(1,4);
                            app.InterpolationPara.reference.b2.std = app.UITable.Data(2,4);
                            app.InterpolationPara.reference.theta1.mean = app.UITable.Data(1,5);
                            app.InterpolationPara.reference.theta1.std = app.UITable.Data(2,5);
                            app.InterpolationPara.reference.theta2.mean = app.UITable.Data(1,6);
                            app.InterpolationPara.reference.theta2.std = app.UITable.Data(2,6);
                            
                            app.InterpolationPara.firstBeat.a1.mean = app.UITable.Data(3,1);
                            app.InterpolationPara.firstBeat.a1.std = app.UITable.Data(4,1);
                            app.InterpolationPara.firstBeat.a2.mean = app.UITable.Data(3,2);
                            app.InterpolationPara.firstBeat.a2.std = app.UITable.Data(4,2);
                            app.InterpolationPara.firstBeat.b1.mean = app.UITable.Data(3,3);
                            app.InterpolationPara.firstBeat.b1.std = app.UITable.Data(4,3);
                            app.InterpolationPara.firstBeat.b2.mean = app.UITable.Data(3,4);
                            app.InterpolationPara.firstBeat.b2.std = app.UITable.Data(4,4);
                            app.InterpolationPara.firstBeat.theta1.mean = app.UITable.Data(3,5);
                            app.InterpolationPara.firstBeat.theta1.std = app.UITable.Data(4,5);
                            app.InterpolationPara.firstBeat.theta2.mean = app.UITable.Data(3,6);
                            app.InterpolationPara.firstBeat.theta2.std = app.UITable.Data(4,6);
                            app.InterpolationPara.firstBeat.durationRatio = app.UITable.Data(3,7);
                            
                            app.InterpolationPara.secondBeat.a1.mean = app.UITable.Data(5,1);
                            app.InterpolationPara.secondBeat.a1.std = app.UITable.Data(6,1);
                            app.InterpolationPara.secondBeat.a2.mean = app.UITable.Data(5,2);
                            app.InterpolationPara.secondBeat.a2.std = app.UITable.Data(6,2);
                            app.InterpolationPara.secondBeat.b1.mean = app.UITable.Data(5,3);
                            app.InterpolationPara.secondBeat.b1.std = app.UITable.Data(6,3);
                            app.InterpolationPara.secondBeat.b2.mean = app.UITable.Data(5,4);
                            app.InterpolationPara.secondBeat.b2.std = app.UITable.Data(6,4);
                            app.InterpolationPara.secondBeat.theta1.mean = app.UITable.Data(5,5);
                            app.InterpolationPara.secondBeat.theta1.std = app.UITable.Data(6,5);
                            app.InterpolationPara.secondBeat.theta2.mean = app.UITable.Data(5,6);
                            app.InterpolationPara.secondBeat.theta2.std = app.UITable.Data(6,6);
                            app.InterpolationPara.secondBeat.durationRatio = app.UITable.Data(5,7);
                    end
                end
            end
            
        end

        % Button pushed function: OkButton
        function OkButtonPushed(app, event)
            app.CallingApp.updatePamatersFromDlg(app.regularPara,app.compensationPara,app.resetPara,app.InterpolationPara);
            app.CallingApp.EditButton.Enable = 'on';
            delete(app);
        end

        % Close request function: EditPulseParametersUIFigure
        function EditPulseParametersUIFigureCloseRequest(app, event)
            app.CallingApp.EditButton.Enable = 'on';
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.EditPulseParametersUIFigure = uifigure('Visible', 'off');
            app.EditPulseParametersUIFigure.Name = 'Edit Pulse Parameters';
            app.EditPulseParametersUIFigure.CloseRequestFcn = createCallbackFcn(app, @EditPulseParametersUIFigureCloseRequest, true);
            app.UITable = uitable(app.EditPulseParametersUIFigure);
            app.UITable.ColumnName = {'a1'; 'a2'; 'b1'; 'b2'; 'theta1'; 'theta2'; 'duration ratio'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = true;
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.OkButton = uibutton(app.EditPulseParametersUIFigure, 'push');
            app.OkButton.ButtonPushedFcn = createCallbackFcn(app, @OkButtonPushed, true);
            app.OkButton.Text = 'Ok';
            app.PPGTypeLabel = uilabel(app.EditPulseParametersUIFigure);
            app.PPGTypeLabel.Text = 'PPG Type';
            app.PPGTypeDropDown = uidropdown(app.EditPulseParametersUIFigure);
            app.PPGTypeDropDown.Items = {'Regular', 'Compensation', 'Reset', 'Interpolation'};
            app.PPGTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @PPGTypeDropDownValueChanged, true);
            app.PPGTypeDropDown.Value = 'Regular';
            % Show the figure after all components are created
            app.EditPulseParametersUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EditParameters(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.EditPulseParametersUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.EditPulseParametersUIFigure)
        end
    end
end