classdef PPGGeneratorMain < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PPGSynthUIFigure                matlab.ui.Figure
        PPGTypeDropDownLabel            matlab.ui.control.Label
        PPGTypeDropDown                 matlab.ui.control.DropDown
        UIAxes                          matlab.ui.control.UIAxes
        PulseDurationInfoPanel          matlab.ui.container.Panel
        StdRRmsEditFieldLabel           matlab.ui.control.Label
        StdRRmsEditField                matlab.ui.control.NumericEditField
        MeanHRbpmEditFieldLabel         matlab.ui.control.Label
        MeanHRbpmEditField              matlab.ui.control.NumericEditField
        IrregularDurationInfoPanel      matlab.ui.container.Panel
        BasicHRbpmEditFieldLabel        matlab.ui.control.Label
        BasicHRbpmEditField             matlab.ui.control.NumericEditField
        ArrythmiaTimesEditField         matlab.ui.control.NumericEditField
        IrregularTimesLabel             matlab.ui.control.Label
        Button                          matlab.ui.control.Button
        Button_2                        matlab.ui.control.Button
        SaveButton                      matlab.ui.control.Button
        BasicInfoPanel                  matlab.ui.container.Panel
        LengthsEditFieldLabel           matlab.ui.control.Label
        LengthsEditField                matlab.ui.control.NumericEditField
        SampleFrequencyHzEditFieldLabel  matlab.ui.control.Label
        SampleFrequencyHzEditField      matlab.ui.control.NumericEditField
        PulseParametersLabel            matlab.ui.control.Label
        EditButton                      matlab.ui.control.Button
        NoiseInfoPanel                  matlab.ui.container.Panel
        NoiseTypeDropDownLabel          matlab.ui.control.Label
        NoiseTypeDropDown               matlab.ui.control.DropDown
        SNRPanel                        matlab.ui.container.Panel
        SNRdBEditFieldLabel             matlab.ui.control.Label
        SNRdBEditField                  matlab.ui.control.NumericEditField
        MaxValuePanel                   matlab.ui.container.Panel
        Label                           matlab.ui.control.Label
        MaxRandomValueEditField         matlab.ui.control.NumericEditField
        RandomvalueRangeEditFieldLabel  matlab.ui.control.Label
        RandomvalueRangeEditField       matlab.ui.control.NumericEditField
        MultiFrequenciesPanel           matlab.ui.container.Panel
        AmplitudeEditFieldLabel         matlab.ui.control.Label
        AmplitudeEditField              matlab.ui.control.EditField
        FrequencyEditFieldLabel         matlab.ui.control.Label
        FrequencyEditField              matlab.ui.control.EditField
    end

    
    properties (Access = private)
        normal_thetai = [-1.5161,0.8186];  % the center of the peak of the Gaussian functions
        normal_ai = [1.0000,0.1999];  % the amplitude of the peak of the Gaussion functions.
        normal_bi = [0.6303,1.0225];  % the standard deviation of Gaussian functions.
        
        compensation_firstBeat_ai = [0.8683,0.3492];
        compensation_firstBeat_bi = [0.7230,1.2512];
        compensation_firstBeat_thetai = [-1.3684,0.1584];
        compensation_firstBeat_ratio = 0.8556;
        
        compensation_secondBeat_ai = [0.9252,0.4310];
        compensation_secondBeat_bi = [0.5995,1.5658];
        compensation_secondBeat_thetai = [-1.8071,0.8266];
        compensation_secondBeat_ratio = 1.1111;
        
        reset_firstBeat_ai = [0.7380,0.7380];
        reset_firstBeat_bi = [0.6744,0.9848];
        reset_firstBeat_thetai = [-1.4591,0.0605];
        reset_firstBeat_ratio = 0.5905;
        
        reset_secondBeat_ai = [0.7932,0.5977];
        reset_secondBeat_bi = [0.5000,1.4697];
        reset_secondBeat_thetai = [-2.0008,0.3595];
        reset_secondBeat_ratio = 1.1048;
        
        interpolation_firstBeat_ai = [0.6307,0.6306];
        interpolation_firstBeat_bi = [0.7067,1.1124];
        interpolation_firstBeat_thetai = [-1.2456,-0.0368];
        interpolation_firstBeat_ratio = 0.5699;
        
        interpolation_secondBeat_ai = [0.8256,0.3498];
        interpolation_secondBeat_bi = [0.9716,1.3698];
        interpolation_secondBeat_thetai = [-0.5041,1.0855];
        interpolation_secondBeat_ratio = 0.4946; % Description
        
        Para;
        ParaLoaded = false;
        CompensationPara;
        ResetPara;
        InterpolationPara;
        NormalPara;
        
        EditDialogApp;
        CodeTestFlag;
    end
    
    methods (Access = private)
        
        function results = plot_curve(app)
            global syntheticPPG;
            PPGType = app.PPGTypeDropDown.Value;
            if strcmp(PPGType,'normal')
                HR = app.MeanHRbpmEditField.Value;
            else
                HR = app.BasicHRbpmEditField.Value;
            end
            SDNN = app.StdRRmsEditField.Value;
            Fs = app.SampleFrequencyHzEditField.Value;
            seconds = app.LengthsEditField.Value;
            
            
            arrythmiaNumberVariable = app.ArrythmiaTimesEditField.Value;
            if seconds == 0
                return
            else
                [normalPara,firstBeatPara,secondBeatPara] = getParameters(app,PPGType);
                [ppg,error,onsets,peaks] = app.generatePPG(Fs,seconds,HR,SDNN,PPGType,arrythmiaNumberVariable,normalPara,firstBeatPara,secondBeatPara);
                switch error
                    case 0
                        noiseType = app.NoiseTypeDropDown.Value;
                        SNR = app.SNRdBEditField.Value;
                        maxNoiseValue = app.MaxRandomValueEditField.Value;
                        switch noiseType
                            case 'White Gaussian'
                                var1 = SNR;
                            case 'random'
                                var1 = maxNoiseValue;
                            otherwise
                                var1 = SNR;
                        end
                        ppg = addnoise(app,ppg,noiseType,var1);
                        syntheticPPG = (ppg - min(ppg)) /(max(ppg) - min(ppg));
                        t = 1/Fs:1/Fs:seconds;
                        plot(app.UIAxes,t,syntheticPPG,'-b');
                        app.UIAxes.reset;
                        if app.CodeTestFlag == true
                            hold(app.UIAxes,"on");
                            plot(app.UIAxes,t(onsets),syntheticPPG(onsets),'b*');
                            plot(app.UIAxes,t(peaks),syntheticPPG(peaks),'r+');
                            hold(app.UIAxes,"off");
                        end
                        ylabel(app.UIAxes,'Normalized Amplitude','FontSize',18);
                        xlabel(app.UIAxes,'Time (s)','FontSize',18);
                        title(app.UIAxes,'Synthetic PPG','FontSize',18);
                        results = 0;
                    case 1
                        warndlg('A synthetic PPG of this length cannot have so many irregular pulses.');
                end
            end
        end
        function [PPG,errorNum,onsets,peaks] = generatePPG(app,fs,time,MeanHR,SDNN,ppgType,arrythmiaTimes,NormalPara,firstBeatPara,secondBeatPara)
            
            if strcmp(ppgType,'Regular')
                mu = 60/MeanHR;
                sigma = SDNN/1000;
                [beatInterval,beatType] = app.get_random_NormalBeatInterval(time,mu,sigma);
                errorNum = 0;
            else
                durationRatio = [firstBeatPara.durationRatio secondBeatPara.durationRatio];
                [beatInterval,beatType,errorNum] = app.get_random_abnormalBeatInterval_times(time,MeanHR,arrythmiaTimes,durationRatio);
            end
            if errorNum == 0
                [t,T,ai,bi,thetai,~,beatOnset] = app.modelParaTransform(fs,time,beatInterval,beatType,NormalPara,firstBeatPara,secondBeatPara);

                [PPG,~,~] = app.pulseModel(t,T,ai,bi,thetai);
                
                %% interpolation
                samples = round(fs*0.1);
                onsets(1) = 1;
                for i = 2:length(beatOnset)
                    
                    if beatOnset(i)+samples<length(PPG)
                        ti = (beatOnset(i)-samples):(beatOnset(i)+samples);
                        points_pre = (beatOnset(i)-samples):(beatOnset(i)-floor(samples/2));
                        points_suf = (beatOnset(i)+floor(samples/2)):(beatOnset(i)+samples);
                        points = [points_pre points_suf];
                        waypoints = PPG(points);
                        y1 = spline(points,waypoints,ti);
                        [index,ia] = setdiff(ti,points);
                        PPG(index) = y1(ia);
                        [~,index2] = min(PPG(ti));
                        onsets(i) = beatOnset(i) - samples + index2 -1; 
                        
                    else
                        [~,index2] = min(PPG(beatOnset(i)-samples:min([length(PPG) beatOnset(i)+samples])));
                        onsets(i) = beatOnset(i) - samples + index2 -1; 
                    end
                    [~,index3] = max(PPG(onsets(i-1):onsets(i)));
                    peaks(i-1) = onsets(i-1) + index3 - 1;
                end
                if app.CodeTestFlag == true
                    assignin("base","PPG",PPG);
                    assignin("base","onsets",onsets);
                    assignin("base","peaks",peaks);
                end
            else
                PPG = 0;
            end
        end
        
        function [t,T,ai,bi,thetai,intervals,onset] = modelParaTransform(app,fs,timeLength,durations,beatType,NormalPara,firstBeatPara,secondBeatPara)
            t = 1/fs:1/fs:timeLength;
            T = zeros(length(t),1);
            ai = zeros(length(t),2);
            bi = zeros(length(t),2);
            thetai = zeros(length(t),2);
            sumIntervals = 0;
            beatCount = 0;
            onset = [];
            %% get the number of each beat
            num_normalBeat = numel(find(beatType == 0));
            num_firstBeat = numel(find(beatType == 1));
            num_secondBeat = numel(find(beatType == 2));
            
            %% get the parameters from normal distribution
            NormalBeat_a1 = normrnd(NormalPara.a1.mean,NormalPara.a1.std,[1,num_normalBeat]);
            NormalBeat_a2 = normrnd(NormalPara.a2.mean,NormalPara.a2.std,[1,num_normalBeat]);
            NormalBeat_b1 = normrnd(NormalPara.b1.mean,NormalPara.b1.std,[1,num_normalBeat]);
            NormalBeat_b2 = normrnd(NormalPara.b2.mean,NormalPara.b2.std,[1,num_normalBeat]);
            NormalBeat_theta1 = normrnd(NormalPara.theta1.mean,NormalPara.theta1.std,[1,num_normalBeat]);
            NormalBeat_theta2 = normrnd(NormalPara.theta2.mean,NormalPara.theta2.std,[1,num_normalBeat]);
            
            FirstBeat_a1 = normrnd(firstBeatPara.a1.mean,firstBeatPara.a1.std,[1,num_firstBeat]);
            FirstBeat_a2 = normrnd(firstBeatPara.a2.mean,firstBeatPara.a2.std,[1,num_firstBeat]);
            FirstBeat_b1 = normrnd(firstBeatPara.b1.mean,firstBeatPara.b1.std,[1,num_firstBeat]);
            FirstBeat_b2 = normrnd(firstBeatPara.b2.mean,firstBeatPara.b2.std,[1,num_firstBeat]);
            FirstBeat_theta1 = normrnd(firstBeatPara.theta1.mean,firstBeatPara.theta1.std,[1,num_firstBeat]);
            FirstBeat_theta2 = normrnd(firstBeatPara.theta2.mean,firstBeatPara.theta2.std,[1,num_firstBeat]);
            
            SecondBeat_a1 = normrnd(secondBeatPara.a1.mean,secondBeatPara.a1.std,[1,num_secondBeat]);
            SecondBeat_a2 = normrnd(secondBeatPara.a2.mean,secondBeatPara.a2.std,[1,num_secondBeat]);
            SecondBeat_b1 = normrnd(secondBeatPara.b1.mean,secondBeatPara.b1.std,[1,num_secondBeat]);
            SecondBeat_b2 = normrnd(secondBeatPara.b2.mean,secondBeatPara.b2.std,[1,num_secondBeat]);
            SecondBeat_theta1 = normrnd(secondBeatPara.theta1.mean,secondBeatPara.theta1.std,[1,num_secondBeat]);
            SecondBeat_theta2 = normrnd(secondBeatPara.theta2.mean,secondBeatPara.theta2.std,[1,num_secondBeat]);
            
            NormalBeat_count = 0;
            FirstBeat_count = 0;
            SecondBeat_count = 0;
            for i = 1:length(t)-1
                if t(i) >= sumIntervals -1/fs
                    beatCount = beatCount+1;
                    onset = [onset i];
                    sumIntervals = sumIntervals + durations(beatCount);
                    newBeatFlag = 1;  % start a new beat
                else
                    newBeatFlag = 0;
                end
                T(i) = durations(beatCount);
                if beatType(beatCount) == 0
                    if newBeatFlag == 1
                        NormalBeat_count = NormalBeat_count + 1;
                    end
                    ai(i,1) = NormalBeat_a1(NormalBeat_count);
                    ai(i,2) = NormalBeat_a2(NormalBeat_count);
                    bi(i,1) = NormalBeat_b1(NormalBeat_count);
                    bi(i,2) = NormalBeat_b2(NormalBeat_count);
                    thetai(i,1) = NormalBeat_theta1(NormalBeat_count);
                    thetai(i,2) = NormalBeat_theta2(NormalBeat_count);
                    
                elseif beatType(beatCount) == 1
                    if newBeatFlag == 1
                        FirstBeat_count = FirstBeat_count + 1;
                    end
                    ai(i,1) = FirstBeat_a1(FirstBeat_count);
                    ai(i,2) = FirstBeat_a2(FirstBeat_count);
                    bi(i,1) = FirstBeat_b1(FirstBeat_count);
                    bi(i,2) = FirstBeat_b2(FirstBeat_count);
                    thetai(i,1) = FirstBeat_theta1(FirstBeat_count);
                    thetai(i,2) = FirstBeat_theta2(FirstBeat_count);
                elseif beatType(beatCount) == 2
                    if newBeatFlag == 1
                        SecondBeat_count = SecondBeat_count + 1;
                    end
                    ai(i,1) = SecondBeat_a1(SecondBeat_count);
                    ai(i,2) = SecondBeat_a2(SecondBeat_count);
                    bi(i,1) = SecondBeat_b1(SecondBeat_count);
                    bi(i,2) = SecondBeat_b2(SecondBeat_count);
                    thetai(i,1) = SecondBeat_theta1(SecondBeat_count);
                    thetai(i,2) = SecondBeat_theta2(SecondBeat_count);
                end
            end
            intervals = durations(1:beatCount);
        end
        
        
        function [beatInterval,beatType] = get_random_NormalBeatInterval(app,timeLength,meanValue,stdValue)
            beatInterval = normrnd(meanValue,stdValue,[ceil(timeLength/meanValue * 4),1]);
            beatType = zeros(length(beatInterval),1);
        end
        
        
        function [beatInterval,beatType,errorNum] = get_random_abnormalBeatInterval_times(app,time,hr,arrythmiaTimes,durationRatio)
            errorNum = 0;
            basic_beatNum = ceil(time/(60/hr));
            
            addedBeatNum = floor(arrythmiaTimes*2 - (durationRatio(1) + durationRatio(2))*arrythmiaTimes);
            beatNum = basic_beatNum + addedBeatNum;
            interval = ones(time*4,1) *60/hr;
            beatType = zeros(length(interval),1);
            
            arrythmiaBeatNumber = arrythmiaTimes;
            if  arrythmiaBeatNumber > floor((beatNum-1)/2)
                errorNum = 1;
                beatInterval = 0;
                return
            end
            arrythmiaBeat = randperm(floor((beatNum-1)/2),arrythmiaBeatNumber)*2;
            
            interval(arrythmiaBeat) = interval(arrythmiaBeat)*durationRatio(1);
            beatType(arrythmiaBeat) = 1;
            interval(arrythmiaBeat + 1) = interval(arrythmiaBeat+1)*durationRatio(2);
            beatType(arrythmiaBeat + 1) = 2;
            for i = 1:length(interval)
                if sum(interval(1:i)) > time
                    break;
                end
            end
            beatInterval = interval(1:i);
            beatType = beatType(1:i);
        end
        function results = addnoise(app,signal,type,var1)
            switch(type)
                case 'none'
                    results = signal;
                case 'White Gaussian'
                    results = awgn(signal,var1);
                case 'multi-frequencies'
                    value = app.AmplitudeEditField.Value;
                    value2 = app.FrequencyEditField.Value;
                    amplitudes = str2num(value);
                    frequencies = str2num(value2);
                    if length(amplitudes) == length(frequencies)
                        Fs = app.SampleFrequencyHzEditField.Value;
                        t = (1:length(signal))/Fs;
                        noise = 0;
                        for i = 1:length(amplitudes)
                            noise = noise + amplitudes(i) * sin(2*pi*frequencies(i) * t);
                        end
                        results = signal(:) + noise(:);
                    else
                        warndlg('The number of amplitude and frequency should be same.');
                    end
                case 'random'
                    noise = rand(1,length(signal))*var1;
                    results = signal(:) + noise(:);
                otherwise
                    warndlg(['type:' type ' are not availabel, none noise was added.']);
                    results = signal;
            end
            
        end
        
        function [z,x,y] = pulseModel(app,t,T,ai,bi,thetai)
            len = length(t);
            x = zeros(len,1);
            y = zeros(len,1);
            z = zeros(len,1);
            t_old = 0;
            T_old = 0;
            for count = 1:len
                w = 2*pi/(T(count));
                if T(count) ~= T_old
                    T_old = T(count);
                    if count ~= 1
                        t_old = t(count);
                    end
                end
                x(count,1) = cos(w*(t(count) - t_old) - pi);
                y(count,1) = sin(w*(t(count) - t_old) - pi);
                theta = atan2(y(count),x(count));
                z(count,1) = sum(ai(count,1:2).*exp(-(((theta - thetai(count,1:2))./bi(count,1:2)).^2)/2));
            end
        end
        
        function [normalPara,firstBeatPara,secondBeatPara] = getParameters(app,type)
            if app.ParaLoaded == false
                loadInitPara(app);
            end
            normalPara = app.NormalPara;
            switch type
                case 'Compensation'
                    firstBeatPara = app.CompensationPara.firstBeat;
                    secondBeatPara = app.CompensationPara.secondBeat;
                case 'Reset'
                    firstBeatPara = app.ResetPara.firstBeat;
                    secondBeatPara = app.ResetPara.secondBeat;
                case 'Interpolation'
                    firstBeatPara = app.InterpolationPara.firstBeat;
                    secondBeatPara = app.InterpolationPara.secondBeat;
                otherwise
                    firstBeatPara = app.CompensationPara.firstBeat;
                    secondBeatPara = app.CompensationPara.secondBeat;
            end
            
        end
        
        function results = loadInitPara(app)
            %% normal beat parameters
            app.NormalPara.a1.mean = 0.996742139601780;
            app.NormalPara.a1.std = 0.027682516992608;
            app.NormalPara.a2.mean = 0.225233523003093;
            app.NormalPara.a2.std = 0.030438096390671;
            app.NormalPara.b1.mean = 0.640793772099031;
            app.NormalPara.b1.std = 0.034364304152214;
            app.NormalPara.b2.mean = 0.937456645184277;
            app.NormalPara.b2.std = 0.161211918655441;
            app.NormalPara.theta1.mean = -1.471429947628028;
            app.NormalPara.theta1.std = 0.146924346065821;
            app.NormalPara.theta2.mean = 1.019285469672360;
            app.NormalPara.theta2.std = 0.102342741876411;

            %% compensation beats parameters
            app.CompensationPara.reference = app.NormalPara;            
            app.CompensationPara.firstBeat.a1.mean = 0.828936458322831;
            app.CompensationPara.firstBeat.a1.std = 0.010440924254560;
            app.CompensationPara.firstBeat.a2.mean = 0.420378158458988;
            app.CompensationPara.firstBeat.a2.std = 0.017513045252420;
            app.CompensationPara.firstBeat.b1.mean = 0.732423406710334;
            app.CompensationPara.firstBeat.b1.std = 0.033089066671358;
            app.CompensationPara.firstBeat.b2.mean = 1.219269556227441;
            app.CompensationPara.firstBeat.b2.std = 0.020774189162851;
            app.CompensationPara.firstBeat.theta1.mean = -1.007977342208800;
            app.CompensationPara.firstBeat.theta1.std = 0.146722367424983;
            app.CompensationPara.firstBeat.theta2.mean = 0.450084374894658;
            app.CompensationPara.firstBeat.theta2.std = 0.166547253139125;
            app.CompensationPara.firstBeat.durationRatio = 0.829629629629630;
            
            app.CompensationPara.secondBeat.a1.mean = 0.785285218151355;
            app.CompensationPara.secondBeat.a1.std = 0.034463629791959;
            app.CompensationPara.secondBeat.a2.mean = 0.405050855375575;
            app.CompensationPara.secondBeat.a2.std = 0.048839396126124;
            app.CompensationPara.secondBeat.b1.mean = 0.678238822453314;
            app.CompensationPara.secondBeat.b1.std = 0.036087130181284;
            app.CompensationPara.secondBeat.b2.mean = 1.114899998940235;
            app.CompensationPara.secondBeat.b2.std = 0.065206147063529;
            app.CompensationPara.secondBeat.theta1.mean = -1.792276815241107;
            app.CompensationPara.secondBeat.theta1.std = 0.080252001029385;
            app.CompensationPara.secondBeat.theta2.mean = -0.606749534576011;
            app.CompensationPara.secondBeat.theta2.std = 0.107486466600914;
            app.CompensationPara.secondBeat.durationRatio = 1.170370370370371;
            %% reset beats parameters
            app.ResetPara.reference = app.NormalPara;            
            app.ResetPara.firstBeat.a1.mean = 0.774024975330103;
            app.ResetPara.firstBeat.a1.std = 0.012096556344606;
            app.ResetPara.firstBeat.a2.mean = 0.774023920527996;
            app.ResetPara.firstBeat.a2.std = 0.012097529942792;
            app.ResetPara.firstBeat.b1.mean = 0.647434023732013;
            app.ResetPara.firstBeat.b1.std = 0.040839811601967;
            app.ResetPara.firstBeat.b2.mean = 1.007164586122640;
            app.ResetPara.firstBeat.b2.std = 0.045825135559796;
            app.ResetPara.firstBeat.theta1.mean = -1.378090181987628;
            app.ResetPara.firstBeat.theta1.std = 0.179576294397415;
            app.ResetPara.firstBeat.theta2.mean = 0.173165019731387;
            app.ResetPara.firstBeat.theta2.std = 0.180424364545347;
            app.ResetPara.firstBeat.durationRatio = 0.606818181818182;
            
            app.ResetPara.secondBeat.a1.mean = 0.995175878621272;
            app.ResetPara.secondBeat.a1.std = 0.001792035844560;
            app.ResetPara.secondBeat.a2.mean = 0.197217070680612;
            app.ResetPara.secondBeat.a2.std = 0.023583819686791;
            app.ResetPara.secondBeat.b1.mean = 0.777859672989419;
            app.ResetPara.secondBeat.b1.std = 0.055337488074666;
            app.ResetPara.secondBeat.b2.mean = 1.045175146327772;
            app.ResetPara.secondBeat.b2.std = 0.341195041649078;
            app.ResetPara.secondBeat.theta1.mean = -1.809141917344965;
            app.ResetPara.secondBeat.theta1.std = 0.203389389115647;
            app.ResetPara.secondBeat.theta2.mean = 0.892251774976200;
            app.ResetPara.secondBeat.theta2.std = 0.325121867823407;
            app.ResetPara.secondBeat.durationRatio = 0.595454545454546;


            %% interpolation beats parameters
            app.InterpolationPara.reference = app.NormalPara;            
            app.InterpolationPara.firstBeat.a1.mean = 0.668085554201733;
            app.InterpolationPara.firstBeat.a1.std = 0.150850107800372;
            app.InterpolationPara.firstBeat.a2.mean = 0.489929546563826;
            app.InterpolationPara.firstBeat.a2.std = 0.006377934817525;
            app.InterpolationPara.firstBeat.b1.mean = 0.892818712100714;
            app.InterpolationPara.firstBeat.b1.std = 0.034210016617092;
            app.InterpolationPara.firstBeat.b2.mean = 1.427641686925804;
            app.InterpolationPara.firstBeat.b2.std = 0.062413074197630;
            app.InterpolationPara.firstBeat.theta1.mean = -0.627243983917600;
            app.InterpolationPara.firstBeat.theta1.std = 0.291755436893305;
            app.InterpolationPara.firstBeat.theta2.mean = 0.441561544515304;
            app.InterpolationPara.firstBeat.theta2.std = 0.634512589655537;
            app.InterpolationPara.firstBeat.durationRatio = 0.525925925925926;
            
            app.InterpolationPara.secondBeat.a1.mean = 0.594517201075636;
            app.InterpolationPara.secondBeat.a1.std = 0.083882025410543;
            app.InterpolationPara.secondBeat.a2.mean = 0.536688575379725;
            app.InterpolationPara.secondBeat.a2.std = 0.092157244066697;
            app.InterpolationPara.secondBeat.b1.mean = 0.889148113648354;
            app.InterpolationPara.secondBeat.b1.std = 0.170039470189359;
            app.InterpolationPara.secondBeat.b2.mean = 1.321203859171689;
            app.InterpolationPara.secondBeat.b2.std = 0.288668002285814;
            app.InterpolationPara.secondBeat.theta1.mean = -1.048885695348488;
            app.InterpolationPara.secondBeat.theta1.std = 0.207247183382076;
            app.InterpolationPara.secondBeat.theta2.mean = -0.289468382304167;
            app.InterpolationPara.secondBeat.theta2.std = 0.480469887299681;
            app.InterpolationPara.secondBeat.durationRatio = 0.474074074074074;                   
            app.ParaLoaded = true;
            app.CodeTestFlag = false;
            results = 0;
        end
        
    end
    
    methods (Access = public)
        
        function results = updatePamatersFromDlg(app,normalPara,compensationPara,resetPara,interpolationPara)
            app.NormalPara = normalPara;
            app.CompensationPara = compensationPara;
            app.ResetPara = resetPara;
            app.InterpolationPara = interpolationPara;
            results = 0;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: PPGTypeDropDown
        function PPGTypeDropDownValueChanged(app, event)
            PPGType = app.PPGTypeDropDown.Value;
            if strcmp(PPGType,'Regular')
                app.PulseDurationInfoPanel.Visible = 'on';
                app.IrregularDurationInfoPanel.Visible = 'off';
            else
                app.PulseDurationInfoPanel.Visible = 'off';
                app.IrregularDurationInfoPanel.Visible = 'on';
            end
            plot_curve(app);
        end

        % Value changed function: MeanHRbpmEditField
        function MeanHRbpmEditFieldValueChanged(app, event)
            value = app.MeanHRbpmEditField.Value;
            plot_curve(app);
        end

        % Value changed function: LengthsEditField
        function LengthsEditFieldValueChanged(app, event)
            value = app.LengthsEditField.Value;
            plot_curve(app);
        end

        % Value changed function: SampleFrequencyHzEditField
        function SampleFrequencyHzEditFieldValueChanged(app, event)
            value = app.SampleFrequencyHzEditField.Value;
            plot_curve(app);
        end

        % Value changed function: StdRRmsEditField
        function StdRRmsEditFieldValueChanged(app, event)
            value = app.StdRRmsEditField.Value;
            plot_curve(app);
        end

        % Callback function
        function ArrythmiaRatioEditFieldValueChanged(app, event)
            value = app.ArrythmiaRatioEditField.Value;
            plot_curve(app);
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            zoom(app.UIAxes,'on');
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            app.UIAxes.reset;
            zoom(app.UIAxes,'off');
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            global syntheticPPG;
            filter = {'*.csv';'*.xlsx';'*.mat'};
            [fileName, path,index] = uiputfile(filter,'save PPG','synthetic_ppg');
            if fileName ~= 0
                if isempty(syntheticPPG)
                    warndlg('Please generate PPG first.');
                else
                    switch index
                        case 1
                            csvwrite([path fileName],syntheticPPG);
                        case 2
                            xlswrite([path fileName],syntheticPPG);
                        case 3
                            save([path fileName],'syntheticPPG');
                        case 4
                            k  = strfind(fileName,'.');
                            if isempty(k)
                                return
                            else
                                filetype = fileName(k(end):end);
                                
                                switch(filetype)
                                    case 'mat'
                                        save([path fileName],'syntheticPPG');
                                    case 'xlsx'
                                        xlswrite([path fileName],syntheticPPG);
                                    case 'csv'
                                        csvwrite([path fileName],syntheticPPG);
                                    otherwise
                                        warndlg('This type is not available!');
                                end
                            end
                    end
                end
            end
        end

        % Value changed function: NoiseTypeDropDown
        function NoiseTypeDropDownValueChanged(app, event)
            value = app.NoiseTypeDropDown.Value;
            switch value
                case 'none'
                    app.SNRPanel.Visible = 'off';
                    app.MaxValuePanel.Visible = 'off';
                    app.MultiFrequenciesPanel.Visible = 'off';
                case 'White Gaussian'
                    app.SNRPanel.Visible = 'on';
                    app.MaxValuePanel.Visible = 'off';
                    app.MultiFrequenciesPanel.Visible = 'off';
                case 'multi-frequencies'
                    app.SNRPanel.Visible = 'off';
                    app.MaxValuePanel.Visible = 'off';
                    app.MultiFrequenciesPanel.Visible = 'on';
            end
            plot_curve(app);
        end

        % Value changed function: SNRdBEditField
        function SNRdBEditFieldValueChanged(app, event)
            value = app.SNRdBEditField.Value;
            plot_curve(app);
        end

        % Value changed function: BasicHRbpmEditField
        function BasicHRbpmEditFieldValueChanged(app, event)
            value = app.BasicHRbpmEditField.Value;
            plot_curve(app);
        end

        % Value changed function: RandomvalueRangeEditField
        function RandomvalueRangeEditFieldValueChanged(app, event)
            value = app.RandomvalueRangeEditField.Value;
            plot_curve(app);
        end

        % Callback function
        function versionMenuSelected(app, event)
            msgbox('version 1.0','PPG generator');
        end

        % Value changed function: MaxRandomValueEditField
        function MaxRandomValueEditFieldValueChanged(app, event)
            value = app.MaxRandomValueEditField.Value;
            plot_curve(app);
        end

        % Value changed function: ArrythmiaTimesEditField
        function ArrythmiaTimesEditFieldValueChanged(app, event)
            value = app.ArrythmiaTimesEditField.Value;
            plot_curve(app);
        end

        % Value changed function: AmplitudeEditField
        function AmplitudeEditFieldValueChanged(app, event)
            value = app.AmplitudeEditField.Value;
            value2 = app.FrequencyEditField.Value;
            amplitudes = str2num(value);
            frequencies = str2num(value2);
            if length(amplitudes) == length(frequencies)
                plot_curve(app);
            else
                warndlg('The number of amplitude and frequency should be same.');
            end
        end

        % Value changed function: FrequencyEditField
        function FrequencyEditFieldValueChanged(app, event)
            value = app.AmplitudeEditField.Value;
            value2 = app.FrequencyEditField.Value;
            amplitudes = str2num(value);
            frequencies = str2num(value2);
            if length(amplitudes) == length(frequencies)
                plot_curve(app);
            else
                warndlg('The number of amplitude and frequency should be same.');
            end
            
        end

        % Button pushed function: EditButton
        function EditButtonPushed(app, event)
            app.EditButton.Enable = 'off';
            
            % Open the options dialog and pass inputs


            opts.Interpreter = 'tex';
            PPGType = app.PPGTypeDropDown.Value;
            getParameters(app,PPGType);
            app.EditDialogApp = EditParameters(app, app.NormalPara, app.CompensationPara, app.ResetPara,app.InterpolationPara);
            plot_curve(app);

        end

        % Close request function: PPGSynthUIFigure
        function PPGSynthUIFigureCloseRequest(app, event)
            delete(app.EditDialogApp);
            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.PPGSynthUIFigure = uifigure('Visible', 'off');
            app.PPGSynthUIFigure.Name = 'PPGSynth';
            app.PPGSynthUIFigure.CloseRequestFcn = createCallbackFcn(app, @PPGSynthUIFigureCloseRequest, true);
            app.PPGTypeDropDownLabel = uilabel(app.PPGSynthUIFigure);
            app.PPGTypeDropDownLabel.Text = 'PPG Type';
            app.PPGTypeDropDown = uidropdown(app.PPGSynthUIFigure);
            app.PPGTypeDropDown.Items = {'Regular', 'Compensation', 'Reset', 'Interpolation'};
            app.PPGTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @PPGTypeDropDownValueChanged, true);
            app.PPGTypeDropDown.Value = 'Regular';
            app.UIAxes = uiaxes(app.PPGSynthUIFigure);
            title(app.UIAxes, 'Synthetic PPG')
            xlabel(app.UIAxes, 'Time (s)')
            ylabel(app.UIAxes, 'Normalized Amplitude')
            app.PulseDurationInfoPanel = uipanel(app.PPGSynthUIFigure);
            app.PulseDurationInfoPanel.Title = 'Pulse Duration Info';
            app.StdRRmsEditFieldLabel = uilabel(app.PulseDurationInfoPanel);
            app.StdRRmsEditFieldLabel.Text = 'Std RR (ms)';
            app.StdRRmsEditField = uieditfield(app.PulseDurationInfoPanel, 'numeric');
            app.StdRRmsEditField.Limits = [0 Inf];
            app.StdRRmsEditField.ValueChangedFcn = createCallbackFcn(app, @StdRRmsEditFieldValueChanged, true);
            app.MeanHRbpmEditFieldLabel = uilabel(app.PulseDurationInfoPanel);
            app.MeanHRbpmEditFieldLabel.Text = 'Mean HR (bpm)';
            app.MeanHRbpmEditField = uieditfield(app.PulseDurationInfoPanel, 'numeric');
            app.MeanHRbpmEditField.Limits = [50 180];
            app.MeanHRbpmEditField.ValueChangedFcn = createCallbackFcn(app, @MeanHRbpmEditFieldValueChanged, true);
            app.MeanHRbpmEditField.Value = 60;
            app.IrregularDurationInfoPanel = uipanel(app.PPGSynthUIFigure);
            app.IrregularDurationInfoPanel.Title = 'Irregular Duration Info';
            app.IrregularDurationInfoPanel.Visible = 'off';
            app.BasicHRbpmEditFieldLabel = uilabel(app.IrregularDurationInfoPanel);
            app.BasicHRbpmEditFieldLabel.Text = 'Basic HR (bpm)';
            app.BasicHRbpmEditField = uieditfield(app.IrregularDurationInfoPanel, 'numeric');
            app.BasicHRbpmEditField.Limits = [50 180];
            app.BasicHRbpmEditField.ValueChangedFcn = createCallbackFcn(app, @BasicHRbpmEditFieldValueChanged, true);
            app.BasicHRbpmEditField.Value = 60;
            app.ArrythmiaTimesEditField = uieditfield(app.IrregularDurationInfoPanel, 'numeric');
            app.ArrythmiaTimesEditField.Limits = [0 Inf];
            app.ArrythmiaTimesEditField.ValueDisplayFormat = '%.0f';
            app.ArrythmiaTimesEditField.ValueChangedFcn = createCallbackFcn(app, @ArrythmiaTimesEditFieldValueChanged, true);
            app.IrregularTimesLabel = uilabel(app.IrregularDurationInfoPanel);
            app.IrregularTimesLabel.Text = 'Irregular Times';
            app.Button = uibutton(app.PPGSynthUIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '+';
            app.Button_2 = uibutton(app.PPGSynthUIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '-';
            app.SaveButton = uibutton(app.PPGSynthUIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.BasicInfoPanel = uipanel(app.PPGSynthUIFigure);
            app.BasicInfoPanel.Title = 'Basic Info';
            app.LengthsEditFieldLabel = uilabel(app.BasicInfoPanel);
            app.LengthsEditFieldLabel.Text = 'Length (s)';
            app.LengthsEditField = uieditfield(app.BasicInfoPanel, 'numeric');
            app.LengthsEditField.Limits = [0 Inf];
            app.LengthsEditField.ValueChangedFcn = createCallbackFcn(app, @LengthsEditFieldValueChanged, true);
            app.SampleFrequencyHzEditFieldLabel = uilabel(app.BasicInfoPanel);
            app.SampleFrequencyHzEditFieldLabel.Text = 'Sample Frequency (Hz)';
            app.SampleFrequencyHzEditField = uieditfield(app.BasicInfoPanel, 'numeric');
            app.SampleFrequencyHzEditField.Limits = [1 Inf];
            app.SampleFrequencyHzEditField.ValueDisplayFormat = '%.0f';
            app.SampleFrequencyHzEditField.ValueChangedFcn = createCallbackFcn(app, @SampleFrequencyHzEditFieldValueChanged, true);
            app.SampleFrequencyHzEditField.Value = 125;
            app.PulseParametersLabel = uilabel(app.BasicInfoPanel);
            app.PulseParametersLabel.Text = 'Pulse Parameters';
            app.EditButton = uibutton(app.BasicInfoPanel, 'push');
            app.EditButton.ButtonPushedFcn = createCallbackFcn(app, @EditButtonPushed, true);
            app.EditButton.Text = 'Edit';
            app.NoiseInfoPanel = uipanel(app.PPGSynthUIFigure);
            app.NoiseInfoPanel.Title = 'Noise Info';
            app.NoiseTypeDropDownLabel = uilabel(app.NoiseInfoPanel);
            app.NoiseTypeDropDownLabel.Text = 'Noise Type';
            app.NoiseTypeDropDown = uidropdown(app.NoiseInfoPanel);
            app.NoiseTypeDropDown.Items = {'none', 'White Gaussian', 'multi-frequencies'};
            app.NoiseTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @NoiseTypeDropDownValueChanged, true);
            app.NoiseTypeDropDown.Value = 'none';
            app.SNRPanel = uipanel(app.NoiseInfoPanel);
            app.SNRPanel.Visible = 'off';
            app.SNRdBEditFieldLabel = uilabel(app.SNRPanel);
            app.SNRdBEditFieldLabel.Text = 'SNR (dB)';
            app.SNRdBEditField = uieditfield(app.SNRPanel, 'numeric');
            app.SNRdBEditField.Limits = [0 200];
            app.SNRdBEditField.ValueChangedFcn = createCallbackFcn(app, @SNRdBEditFieldValueChanged, true);
            app.SNRdBEditField.Value = 30;
            app.MaxValuePanel = uipanel(app.NoiseInfoPanel);
            app.MaxValuePanel.Visible = 'off';
            app.Label = uilabel(app.MaxValuePanel);
            app.Label.Text = '-';
            app.MaxRandomValueEditField = uieditfield(app.MaxValuePanel, 'numeric');
            app.MaxRandomValueEditField.Limits = [0 1];
            app.MaxRandomValueEditField.ValueChangedFcn = createCallbackFcn(app, @MaxRandomValueEditFieldValueChanged, true);
            app.MaxRandomValueEditField.Value = 0.5;
            app.RandomvalueRangeEditFieldLabel = uilabel(app.MaxValuePanel);
            app.RandomvalueRangeEditFieldLabel.Text = 'Random value Range';
            app.RandomvalueRangeEditField = uieditfield(app.MaxValuePanel, 'numeric');
            app.RandomvalueRangeEditField.Limits = [0 1];
            app.RandomvalueRangeEditField.ValueChangedFcn = createCallbackFcn(app, @RandomvalueRangeEditFieldValueChanged, true);
            app.RandomvalueRangeEditField.Editable = 'off';
            app.MultiFrequenciesPanel = uipanel(app.NoiseInfoPanel);
            app.MultiFrequenciesPanel.Visible = 'off';
            app.AmplitudeEditFieldLabel = uilabel(app.MultiFrequenciesPanel);
            app.AmplitudeEditFieldLabel.Text = 'Amplitude';
            app.AmplitudeEditField = uieditfield(app.MultiFrequenciesPanel, 'text');
            app.AmplitudeEditField.ValueChangedFcn = createCallbackFcn(app, @AmplitudeEditFieldValueChanged, true);
            app.AmplitudeEditField.Value = '0.5, 0.1';
            app.FrequencyEditFieldLabel = uilabel(app.MultiFrequenciesPanel);
            app.FrequencyEditFieldLabel.Text = 'Frequency';
            app.FrequencyEditField = uieditfield(app.MultiFrequenciesPanel, 'text');
            app.FrequencyEditField.ValueChangedFcn = createCallbackFcn(app, @FrequencyEditFieldValueChanged, true);
            app.FrequencyEditField.Value = '0.3, 50';
            % Show the figure after all components are created
            app.PPGSynthUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PPGGeneratorMain

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PPGSynthUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PPGSynthUIFigure)
        end
    end
end