classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PPGSynthUIFigure                matlab.ui.Figure
        PPGTypeDropDownLabel            matlab.ui.control.Label
        PPGTypeDropDown                 matlab.ui.control.DropDown
        UIAxes                          matlab.ui.control.UIAxes
        HRVInfoPanel                    matlab.ui.container.Panel
        StdRRmsEditFieldLabel           matlab.ui.control.Label
        StdRRmsEditField                matlab.ui.control.NumericEditField
        MeanHRbpmEditFieldLabel         matlab.ui.control.Label
        MeanHRbpmEditField              matlab.ui.control.NumericEditField
        ArrythmiaInfoPanel              matlab.ui.container.Panel
        BasicHRbpmEditFieldLabel        matlab.ui.control.Label
        BasicHRbpmEditField             matlab.ui.control.NumericEditField
        ArrythmiaTimesEditField         matlab.ui.control.NumericEditField
        ArrythmiaTimesLabel             matlab.ui.control.Label
        Button                          matlab.ui.control.Button
        Button_2                        matlab.ui.control.Button
        SaveButton                      matlab.ui.control.Button
        BasicInfoPanel                  matlab.ui.container.Panel
        LengthsEditFieldLabel           matlab.ui.control.Label
        LengthsEditField                matlab.ui.control.NumericEditField
        SampleFrequencyHzEditFieldLabel  matlab.ui.control.Label
        SampleFrequencyHzEditField      matlab.ui.control.NumericEditField
        NoiseInfoPanel                  matlab.ui.container.Panel
        NoiseTypeDropDownLabel          matlab.ui.control.Label
        NoiseTypeDropDown               matlab.ui.control.DropDown
        SNRPanel                        matlab.ui.container.Panel
        SNRdBEditFieldLabel             matlab.ui.control.Label
        SNRdBEditField                  matlab.ui.control.NumericEditField
        MaxValuePanel                   matlab.ui.container.Panel
        Label                           matlab.ui.control.Label
        MaxRandomValueEditField         matlab.ui.control.NumericEditField
        RandomvalueRangeEditFieldLabel  matlab.ui.control.Label
        RandomvalueRangeEditField       matlab.ui.control.NumericEditField
    end

    
    methods (Access = private)
        
        function results = plot_curve(app)
            global syntheticPPG;
            PPGType = app.PPGTypeDropDown.Value;
            if strcmp(PPGType,'normal')
                HR = app.MeanHRbpmEditField.Value;
            else
                HR = app.BasicHRbpmEditField.Value;
            end
            SDNN = app.StdRRmsEditField.Value;
            Fs = app.SampleFrequencyHzEditField.Value;
            seconds = app.LengthsEditField.Value;
            
            arrythmiaNumberType = 'Times';
            arrythmiaNumberVariable = app.ArrythmiaTimesEditField.Value;
            if seconds == 0
                return
            else
                [ppg,error] = ppgGenerator(Fs,[],0,seconds,HR,SDNN,PPGType,arrythmiaNumberVariable,arrythmiaNumberType);
                switch error
                    case 0
                        noiseType = app.NoiseTypeDropDown.Value;
                        SNR = app.SNRdBEditField.Value;
                        maxNoiseValue = app.MaxRandomValueEditField.Value;
                        switch noiseType
                            case 'white'
                                var1 = SNR;
                            case 'random'
                                var1 = maxNoiseValue;
                            otherwise
                                var1 = SNR;
                        end
                        ppg = addnoise(app,ppg,noiseType,var1);
                        syntheticPPG = mapminmax(ppg,0,1);
                        t = 1/Fs:1/Fs:seconds;
                        plot(app.UIAxes,t,syntheticPPG,'-b');
                        app.UIAxes.reset;
                        ylabel(app.UIAxes,'Normalized Amplitude','FontSize',18);
                        xlabel(app.UIAxes,'Time (s)','FontSize',18);
                        title(app.UIAxes,'Synthetic PPG','FontSize',18);
                        results = 0;
                    case 1
                        warndlg('A synthetic PPG of this length cannot have so many arrhythmias.');
                end
            end
        end
        
        function results = addnoise(app,signal,color,var1)
            switch(color)
                case 'none'
                    results = signal;
                case 'white'
                    results = awgn(signal,var1);
                case 'random'
                    noise = rand(1,length(signal))*var1;
                    results = signal + noise;
                otherwise
                    warndlg(['type:' color ' are not availabel, none noise was added.']);
                    results = signal;
            end
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: PPGTypeDropDown
        function PPGTypeDropDownValueChanged(app, event)
            PPGType = app.PPGTypeDropDown.Value;
            if strcmp(PPGType,'normal')
                app.HRVInfoPanel.Visible = 'on';
                app.ArrythmiaInfoPanel.Visible = 'off';
            else
                app.HRVInfoPanel.Visible = 'off';
                app.ArrythmiaInfoPanel.Visible = 'on';
            end
            plot_curve(app);
        end

        % Value changed function: MeanHRbpmEditField
        function MeanHRbpmEditFieldValueChanged(app, event)
            value = app.MeanHRbpmEditField.Value;
            plot_curve(app);
        end

        % Value changed function: LengthsEditField
        function LengthsEditFieldValueChanged(app, event)
            value = app.LengthsEditField.Value;
            plot_curve(app);
        end

        % Value changed function: SampleFrequencyHzEditField
        function SampleFrequencyHzEditFieldValueChanged(app, event)
            value = app.SampleFrequencyHzEditField.Value;
            plot_curve(app);
        end

        % Value changed function: StdRRmsEditField
        function StdRRmsEditFieldValueChanged(app, event)
            value = app.StdRRmsEditField.Value;
            plot_curve(app);
        end

        % Callback function
        function ArrythmiaRatioEditFieldValueChanged(app, event)
            value = app.ArrythmiaRatioEditField.Value;
            plot_curve(app);
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            zoom(app.UIAxes,'on');            
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            app.UIAxes.reset;
            zoom(app.UIAxes,'off');
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            global syntheticPPG;
            filter = {'*.csv';'*.xlsx';'*.mat'};
            [fileName, path,index] = uiputfile(filter,'save PPG','synthetic_ppg');
            if fileName ~= 0
                if isempty(syntheticPPG)
                    warndlg('Please generate PPG first.');
                else
                    switch index
                        case 1
                            csvwrite([path fileName],syntheticPPG);
                        case 2
                            xlswrite([path fileName],syntheticPPG);
                        case 3
                            save([path fileName],'syntheticPPG');
                        case 4
                            k  = strfind(fileName,'.');
                            if isempty(k)
                                return
                            else
                                filetype = fileName(k(end):end);
                                
                                switch(filetype)
                                    case 'mat'
                                        save([path fileName],'syntheticPPG');
                                    case 'xlsx'
                                        xlswrite([path fileName],syntheticPPG);
                                    case 'csv'
                                        csvwrite([path fileName],syntheticPPG);
                                    otherwise
                                        warndlg('This type is not available!');
                                end
                            end
                    end
                end
            end
        end

        % Value changed function: NoiseTypeDropDown
        function NoiseTypeDropDownValueChanged(app, event)
            value = app.NoiseTypeDropDown.Value;
            switch value
                case 'none'
                    app.SNRPanel.Visible = 'off';
                    app.MaxValuePanel.Visible = 'off';
                case 'white'
                    app.SNRPanel.Visible = 'on';
                    app.MaxValuePanel.Visible = 'off';
                case 'random'
                    app.SNRPanel.Visible = 'off';
                    app.MaxValuePanel.Visible = 'on';
            end
            plot_curve(app);
        end

        % Value changed function: SNRdBEditField
        function SNRdBEditFieldValueChanged(app, event)
            value = app.SNRdBEditField.Value;
            plot_curve(app);
        end

        % Value changed function: BasicHRbpmEditField
        function BasicHRbpmEditFieldValueChanged(app, event)
            value = app.BasicHRbpmEditField.Value;
            plot_curve(app);
        end

        % Value changed function: RandomvalueRangeEditField
        function RandomvalueRangeEditFieldValueChanged(app, event)
            value = app.RandomvalueRangeEditField.Value;
            plot_curve(app);
        end

        % Callback function
        function versionMenuSelected(app, event)
            msgbox('version 1.0','PPG generator');
        end

        % Value changed function: MaxRandomValueEditField
        function MaxRandomValueEditFieldValueChanged(app, event)
            value = app.MaxRandomValueEditField.Value;
            plot_curve(app);
        end

        % Value changed function: ArrythmiaTimesEditField
        function ArrythmiaTimesEditFieldValueChanged(app, event)
            value = app.ArrythmiaTimesEditField.Value;
            plot_curve(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.PPGSynthUIFigure = uifigure('Visible', 'off');
            app.PPGSynthUIFigure.Name = 'PPGSynth';
            app.PPGTypeDropDownLabel = uilabel(app.PPGSynthUIFigure);
            app.PPGTypeDropDownLabel.Text = 'PPG Type';
            app.PPGTypeDropDown = uidropdown(app.PPGSynthUIFigure);
            app.PPGTypeDropDown.Items = {'normal', 'compensation', 'reset', 'interpolation', 'reentry'};
            app.PPGTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @PPGTypeDropDownValueChanged, true);
            app.PPGTypeDropDown.Value = 'normal';
            app.UIAxes = uiaxes(app.PPGSynthUIFigure);
            title(app.UIAxes, 'Synthetic PPG')
            xlabel(app.UIAxes, 'Time (s)')
            ylabel(app.UIAxes, 'Normalized Amplitude')
            app.HRVInfoPanel = uipanel(app.PPGSynthUIFigure);
            app.HRVInfoPanel.Title = 'HRV Info';
            app.StdRRmsEditFieldLabel = uilabel(app.HRVInfoPanel);
            app.StdRRmsEditFieldLabel.Text = 'Std RR (ms)';
            app.StdRRmsEditField = uieditfield(app.HRVInfoPanel, 'numeric');
            app.StdRRmsEditField.Limits = [0 Inf];
            app.StdRRmsEditField.ValueChangedFcn = createCallbackFcn(app, @StdRRmsEditFieldValueChanged, true);
            app.MeanHRbpmEditFieldLabel = uilabel(app.HRVInfoPanel);
            app.MeanHRbpmEditFieldLabel.Text = 'Mean HR (bpm)';
            app.MeanHRbpmEditField = uieditfield(app.HRVInfoPanel, 'numeric');
            app.MeanHRbpmEditField.Limits = [50 180];
            app.MeanHRbpmEditField.ValueChangedFcn = createCallbackFcn(app, @MeanHRbpmEditFieldValueChanged, true);
            app.MeanHRbpmEditField.Value = 60;
            app.ArrythmiaInfoPanel = uipanel(app.PPGSynthUIFigure);
            app.ArrythmiaInfoPanel.Title = 'Arrythmia Info';
            app.ArrythmiaInfoPanel.Visible = 'off';
            app.BasicHRbpmEditFieldLabel = uilabel(app.ArrythmiaInfoPanel);
            app.BasicHRbpmEditFieldLabel.Text = 'Basic HR (bpm)';
            app.BasicHRbpmEditField = uieditfield(app.ArrythmiaInfoPanel, 'numeric');
            app.BasicHRbpmEditField.Limits = [50 180];
            app.BasicHRbpmEditField.ValueChangedFcn = createCallbackFcn(app, @BasicHRbpmEditFieldValueChanged, true);
            app.BasicHRbpmEditField.Value = 60;
            app.ArrythmiaTimesEditField = uieditfield(app.ArrythmiaInfoPanel, 'numeric');
            app.ArrythmiaTimesEditField.Limits = [0 Inf];
            app.ArrythmiaTimesEditField.ValueDisplayFormat = '%.0f';
            app.ArrythmiaTimesEditField.ValueChangedFcn = createCallbackFcn(app, @ArrythmiaTimesEditFieldValueChanged, true);
            app.ArrythmiaTimesLabel = uilabel(app.ArrythmiaInfoPanel);
            app.ArrythmiaTimesLabel.Text = 'Arrythmia Times';
            app.Button = uibutton(app.PPGSynthUIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '+';
            app.Button_2 = uibutton(app.PPGSynthUIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '-';
            app.SaveButton = uibutton(app.PPGSynthUIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.BasicInfoPanel = uipanel(app.PPGSynthUIFigure);
            app.BasicInfoPanel.Title = 'Basic Info';
            app.LengthsEditFieldLabel = uilabel(app.BasicInfoPanel);
            app.LengthsEditFieldLabel.Text = 'Length (s)';
            app.LengthsEditField = uieditfield(app.BasicInfoPanel, 'numeric');
            app.LengthsEditField.Limits = [0 Inf];
            app.LengthsEditField.ValueChangedFcn = createCallbackFcn(app, @LengthsEditFieldValueChanged, true);
            app.SampleFrequencyHzEditFieldLabel = uilabel(app.BasicInfoPanel);
            app.SampleFrequencyHzEditFieldLabel.Text = 'Sample Frequency (Hz)';
            app.SampleFrequencyHzEditField = uieditfield(app.BasicInfoPanel, 'numeric');
            app.SampleFrequencyHzEditField.Limits = [1 Inf];
            app.SampleFrequencyHzEditField.ValueDisplayFormat = '%.0f';
            app.SampleFrequencyHzEditField.ValueChangedFcn = createCallbackFcn(app, @SampleFrequencyHzEditFieldValueChanged, true);
            app.SampleFrequencyHzEditField.Value = 125;
            app.NoiseInfoPanel = uipanel(app.PPGSynthUIFigure);
            app.NoiseInfoPanel.Title = 'Noise Info';
            app.NoiseTypeDropDownLabel = uilabel(app.NoiseInfoPanel);
            app.NoiseTypeDropDownLabel.Text = 'Noise Type';
            app.NoiseTypeDropDown = uidropdown(app.NoiseInfoPanel);
            app.NoiseTypeDropDown.Items = {'none', 'white', 'random'};
            app.NoiseTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @NoiseTypeDropDownValueChanged, true);
            app.NoiseTypeDropDown.Value = 'none';
            app.SNRPanel = uipanel(app.NoiseInfoPanel);
            app.SNRPanel.Visible = 'off';
            app.SNRdBEditFieldLabel = uilabel(app.SNRPanel);
            app.SNRdBEditFieldLabel.Text = 'SNR (dB)';
            app.SNRdBEditField = uieditfield(app.SNRPanel, 'numeric');
            app.SNRdBEditField.Limits = [0 200];
            app.SNRdBEditField.ValueChangedFcn = createCallbackFcn(app, @SNRdBEditFieldValueChanged, true);
            app.SNRdBEditField.Value = 30;
            app.MaxValuePanel = uipanel(app.NoiseInfoPanel);
            app.MaxValuePanel.Visible = 'off';
            app.Label = uilabel(app.MaxValuePanel);
            app.Label.Text = '-';
            app.MaxRandomValueEditField = uieditfield(app.MaxValuePanel, 'numeric');
            app.MaxRandomValueEditField.Limits = [0 1];
            app.MaxRandomValueEditField.ValueChangedFcn = createCallbackFcn(app, @MaxRandomValueEditFieldValueChanged, true);
            app.MaxRandomValueEditField.Value = 0.5;
            app.RandomvalueRangeEditFieldLabel = uilabel(app.MaxValuePanel);
            app.RandomvalueRangeEditFieldLabel.Text = 'Random value Range';
            app.RandomvalueRangeEditField = uieditfield(app.MaxValuePanel, 'numeric');
            app.RandomvalueRangeEditField.Limits = [0 1];
            app.RandomvalueRangeEditField.ValueChangedFcn = createCallbackFcn(app, @RandomvalueRangeEditFieldValueChanged, true);
            app.RandomvalueRangeEditField.Editable = 'off';
            % Show the figure after all components are created
            app.PPGSynthUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PPGSynthUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PPGSynthUIFigure)
        end
    end
end